classdef seg_astro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        QuantificationPanel           matlab.ui.container.Panel
        runButton                     matlab.ui.control.Button
        Branch2ndDetectionPanel       matlab.ui.container.Panel
        RefinebranchesButton_2        matlab.ui.control.Button
        WidthThresholdSpinner_2       matlab.ui.control.Spinner
        WidthThresholdSpinner_2Label  matlab.ui.control.Label
        MaskInputSkiptheastrocytedetectionPanel  matlab.ui.container.Panel
        mito_maskTextArea             matlab.ui.control.TextArea
        mito_maskButton               matlab.ui.control.Button
        astrocyteDataTextArea         matlab.ui.control.TextArea
        astrocyte_dataButton          matlab.ui.control.Button
        loadmasksandastrocyterawdataButton  matlab.ui.control.Button
        astrocyteTextArea             matlab.ui.control.TextArea
        astrocyte_maskButton          matlab.ui.control.Button
        AstrocytedetectionPanel       matlab.ui.container.Panel
        DetectAstrocyteButton         matlab.ui.control.Button
        BranchTuning1stPanel          matlab.ui.container.Panel
        RefinebranchesButton          matlab.ui.control.Button
        WidthThresholdSpinner         matlab.ui.control.Spinner
        WidthThresholdSpinnerLabel    matlab.ui.control.Label
        Branch1stDetectionPanel       matlab.ui.container.Panel
        DetectbranchesButton          matlab.ui.control.Button
        MitochondriadetectionPanel    matlab.ui.container.Panel
        DetectmitochondriaButton      matlab.ui.control.Button
        InputPanel                    matlab.ui.container.Panel
        loaddataButton                matlab.ui.control.Button
        mitochondriaTextArea          matlab.ui.control.TextArea
        astrotextarea                 matlab.ui.control.TextArea
        mitochondriaButton            matlab.ui.control.Button
        astrocyteButton               matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        visualizationPanel            matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        file1 %file name for the raw data of astrocyte
        file2 %file name for the raw data of mitochondria
        file3 %file name for the mask of astrocyte
        file4 %file name for the mask of mitochondria
        path
        newFolder
        astro_im
        mito_im
        lenx
        leny
        visualize_raw
        visualize_mito_seg
        visualize_astro_seg
        visualize_branch_seg
        xxshift
        yyshift
        smoFactor
        zthres0
        mito_mask
        astro_mask
        wideBranch
        thinBranch
        masksspSkel
        maskDist
        terminalBranch
        thinBranch_1
        thinBranch_2
        thinBranchskel
        thinBranchDist
        wideBP;
        widebranch_idx;
        wideBranchWidthMean;
        wideMaskWS2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: astrocyteButton
        function astrocyteButtonPushed(app, event)
            [app.file1,app.path] = uigetfile('*.tif');
            if isequal(app.file1,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(app.path,app.file1)]);
               app.astrotextarea.Value = app.file1;
               imregion = im2double(tiffreadVolume(fullfile(app.path,app.file1)));
               imregion = imregion./max(imregion(:)).*255;
               app.astro_im = imregion;
            end
            [app.lenx, app.leny] = size(app.astro_im);
        end

        % Button pushed function: mitochondriaButton
        function mitochondriaButtonPushed(app, event)
            [app.file2,app.path] = uigetfile('*.tif');
            if isequal(app.file2,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(app.path,app.file2)]);
               app.mitochondriaTextArea.Value = app.file2;
               imregion = im2double(tiffreadVolume(fullfile(app.path,app.file2)));
               imregion = imregion./max(imregion(:)).*255;
               app.mito_im = imregion;
            end       
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Loading data',...
                'Indeterminate','on');
            drawnow
            [~,filename, ~] = fileparts(app.file1);
            app.newFolder = fullfile(app.path, filename);
            mkdir(app.newFolder);
            iters1 = 1; %11x11x5
            xxshift1 = zeros(2*iters1+1, 2*iters1+1);
            yyshift1 = zeros(2*iters1+1, 2*iters1+1);
            for i = -iters1:iters1
                for j = -iters1:iters1
                    xxshift1(i+iters1+1,j+iters1+1) = i;
                    yyshift1(i+iters1+1,j+iters1+1) = j;
                end
            end
            app.xxshift = xxshift1;
            app.yyshift = yyshift1;
            app.smoFactor = 1;
            app.zthres0 = 2;
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,3) = app.mito_im./255;
            visualizetmp(:,:,2) = app.astro_im ./255;
            app.visualize_raw = visualizetmp;
            imshow(app.visualize_raw,'Parent',app.UIAxes);
            
            close(d);
            close(fig);
        end

        % Button pushed function: DetectmitochondriaButton
        function DetectmitochondriaButtonPushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Start Mitochondria Detection');
            pause(2)
            imregion = app.mito_im;
            mito_detection = mito_detect(imregion,app.xxshift, app.yyshift, app.smoFactor, app.zthres0);
            imwrite(double(mito_detection),fullfile(app.newFolder, 'mito_detection.tif'));
            app.mito_mask = mito_detection;
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,1) = app.mito_mask;
            visualizetmp(:,:,2) = app.mito_im./255;
            app.visualize_mito_seg = visualizetmp;
            imshow(app.visualize_mito_seg,'Parent',app.UIAxes);        
            d.Value = 1;
            d.Message = 'Finishing';
            close(d)
            close(fig)
        end

        % Button pushed function: DetectAstrocyteButton
        function DetectAstrocyteButtonPushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Start Astrocyte Detection (It might take 10 minutes)');
            pause(2)
            mask_ssp = detect_astrocyte_v1(app.astro_im, app.xxshift, app.yyshift, app.smoFactor, app.zthres0, app.newFolder);
            app.astro_mask = mask_ssp; 
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,2) = app.astro_im./255;
            visualizetmp(:,:,1) = app.astro_mask;
            app.visualize_astro_seg = visualizetmp;
            imshow(app.visualize_astro_seg,'Parent',app.UIAxes);    
            d.Value = 1;
            d.Message = 'Finishing';
            close(d)
            close(fig)
        end

        % Button pushed function: DetectbranchesButton
        function DetectbranchesButtonPushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Start detecting main, fine and terminal branches');
            pause(2)
            thres0 = app.WidthThresholdSpinner.Value;
            maskssp = app.astro_mask;
            app.masksspSkel = bwskel(logical(maskssp),'MinBranchLength',15);
            app.maskDist = bwdist(1 - maskssp);
            [widePart, thinPart, terminalBranchOut, app.wideBP, app.widebranch_idx, app.wideBranchWidthMean, app.wideMaskWS2] = genCompartmentWideThin(maskssp, app.masksspSkel,app.maskDist, thres0,app.xxshift, app.yyshift);
%             app.wideBP = wideBP;
%             app.widebranch_idx = widebranch_idx;
%             app.wideBranchWidthMean = wideBranchWidthMean;
%             app.wideMaskWS2 = wideMaskWS2;
            imwrite(double(terminalBranchOut), fullfile(app.newFolder, 'terminalBranch_detection.tif'))
            app.terminalBranch = terminalBranchOut;
            app.wideBranch = widePart;
            app.thinBranch = thinPart;
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,2) = app.astro_im./255;
            visualizetmp(:,:,1) = app.wideBranch;
            visualizetmp(:,:,3) = app.thinBranch;
            
            app.visualize_branch_seg = visualizetmp;
            imshow(app.visualize_branch_seg,'Parent',app.UIAxes);    
            d.Value = 1;
            d.Message = 'Finishing';
            close(d)
            close(fig)
        end

        % Button pushed function: astrocyte_maskButton
        function astrocyte_maskButtonPushed(app, event)
            [app.file3,app.path] = uigetfile('*.tif');
            if isequal(app.file3,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(app.path,app.file3)]);
               app.newFolder = app.path;
               app.astrocyteTextArea.Value = app.file3;
               mask_astro = im2double(tiffreadVolume(fullfile(app.path,app.file3)));
               app.astro_mask = mask_astro;
            end
            [app.lenx, app.leny] = size(app.astro_mask);  
        end

        % Button pushed function: astrocyte_dataButton
        function astrocyte_dataButtonPushed(app, event)
            [app.file1,app.path] = uigetfile('*.tif');
            if isequal(app.file1,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(app.path,app.file1)]);
               app.astrocyteDataTextArea.Value = app.file1;
               imregion = im2double(tiffreadVolume(fullfile(app.path,app.file1)));
               imregion = imregion./max(imregion(:)).*255;
               app.astro_im = imregion;
               [~,filename, ~] = fileparts(app.file1);
               app.newFolder = app.path;
            end
            [app.lenx, app.leny] = size(app.astro_im);              
        end

        % Button pushed function: loadmasksandastrocyterawdataButton
        function loadmasksandastrocyterawdataButtonPushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Loading data',...
                'Indeterminate','on');
            drawnow            
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,2) = app.astro_im./255;
            visualizetmp(:,:,1) = app.astro_mask;
            app.visualize_astro_seg = visualizetmp;
            imshow(app.visualize_astro_seg,'Parent',app.UIAxes); 
            
            iters1 = 1; %11x11x5
            xxshift1 = zeros(2*iters1+1, 2*iters1+1);
            yyshift1 = zeros(2*iters1+1, 2*iters1+1);
            for i = -iters1:iters1
                for j = -iters1:iters1
                    xxshift1(i+iters1+1,j+iters1+1) = i;
                    yyshift1(i+iters1+1,j+iters1+1) = j;
                end
            end
            app.xxshift = xxshift1;
            app.yyshift = yyshift1;
            
            d.Value = 1;
            d.Message = 'Finishing';
            close(d)
            close(fig)            
        end

        % Value changed function: WidthThresholdSpinner
        function WidthThresholdSpinnerValueChanged(app, event)
            value = app.WidthThresholdSpinner.Value;
            [widePart, thinPart] = genCompartmentWideThin_update2(app.astro_mask, app.widebranch_idx, app.wideBranchWidthMean, app.wideMaskWS2, value);
            app.wideBranch = widePart;
            app.thinBranch = thinPart;
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,2) = app.astro_im./255;
            visualizetmp(:,:,1) = app.wideBranch;
            visualizetmp(:,:,3) = app.thinBranch;
            app.visualize_branch_seg = visualizetmp;
            imshow(app.visualize_branch_seg,'Parent',app.UIAxes);    
        end

        % Button pushed function: RefinebranchesButton
        function RefinebranchesButtonPushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','refine branches',...
                'Indeterminate','on');
            drawnow    
            [refinedWidePart] = refineWideCompartment(app.wideBranch, app.wideBP);
            refinedWidePart = refinedWidePart.*app.astro_mask;
            se = strel('disk',1);
            refinedWidePart = imclose(refinedWidePart,se);
            refinedThinPart = app.astro_mask - refinedWidePart;
            refinedThinPart(refinedThinPart < 0) = 0;
            refinedWidePart(refinedWidePart <0) = 0;
            app.wideBranch = refinedWidePart;
            app.thinBranch = refinedThinPart;
            app.thinBranchskel = bwskel(logical(refinedThinPart),'MinBranchLength',15);
            app.thinBranchDist = bwdist(1 - refinedThinPart);
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,2) = app.astro_im./255;
            visualizetmp(:,:,1) = app.wideBranch;
            visualizetmp(:,:,3) = app.thinBranch;
            app.visualize_branch_seg = visualizetmp;
            imshow(app.visualize_branch_seg,'Parent',app.UIAxes);   
            imwrite(double(refinedWidePart),fullfile(app.newFolder, 'refined_main_branch.tif'));
            imwrite(double(refinedThinPart),fullfile(app.newFolder, 'refined_layer1_branch.tif'));
            d.Value = 1;
            d.Message = 'Finishing';
            close(d)
            close(fig)    
        end

        % Value changed function: WidthThresholdSpinner_2
        function WidthThresholdSpinner_2ValueChanged(app, event)
            value = app.WidthThresholdSpinner_2.Value;
            [widePart2, thinPart2] = genCompartmentWideThin_update(app.thinBranch, app.thinBranchskel,app.thinBranchDist, value);
            widePart2(widePart2 < 0) = 0;
            thinPart2(thinPart2 < 0) = 0;
            app.thinBranch_1 = widePart2;% the secondary branches 
            app.thinBranch_2 = thinPart2;% the tetary and fourth branches
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,2) = app.astro_im./255;
            visualizetmp(:,:,1) = app.thinBranch_1;
            visualizetmp(:,:,3) = app.thinBranch_2;
            app.visualize_branch_seg = visualizetmp;
            imshow(app.visualize_branch_seg,'Parent',app.UIAxes);  
        end

        % Button pushed function: RefinebranchesButton_2
        function RefinebranchesButton_2Pushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','refine branches',...
                'Indeterminate','on');
            drawnow    
            [refinedThinPart_1] = refineLayer1Compartment2(app.wideBranch, app.thinBranch_1, app.thinBranch_2);
             refinedThinPart_2 = app.thinBranch - refinedThinPart_1;
            app.thinBranch_1 = refinedThinPart_1;
            app.thinBranch_2 = refinedThinPart_2;
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,2) = app.astro_im./255;
            visualizetmp(:,:,1) = app.thinBranch_1;
            visualizetmp(:,:,3) = app.thinBranch_2;
            app.visualize_branch_seg = visualizetmp;
            imshow(app.visualize_branch_seg,'Parent',app.UIAxes);  
            imwrite(double(refinedThinPart_1),fullfile(app.newFolder, 'refined_layer2_branch.tif'));
            imwrite(double(refinedThinPart_2),fullfile(app.newFolder, 'refined_layer34_branch.tif'));            
            d.Value = 1;
            d.Message = 'Finishing';
            close(d)
            close(fig)   
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','quantification starts',...
                'Indeterminate','on');
            drawnow    
            quantifyx(app.mito_mask, app.wideBranch, app.thinBranch_1, app.thinBranch_2, app.terminalBranch, app.newFolder, app.xxshift, app.yyshift)
            quantify_branch(app.wideBranch, app.thinBranch_1, app.thinBranch_2, app.terminalBranch, app.newFolder)
            d.Value = 1;
            d.Message = 'Finishing';
            close(d)
            close(fig) 
        end

        % Button pushed function: mito_maskButton
        function mito_maskButtonPushed(app, event)
            [app.file4,app.path] = uigetfile('*.tif');
            if isequal(app.file4,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(app.path,app.file4)]);
               app.newFolder = app.path;
               app.mito_maskTextArea.Value = app.file4;
               mito_mask = im2double(tiffreadVolume(fullfile(app.path,app.file4)));
               app.mito_mask = mito_mask;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {733, 733};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {233, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputPanel = uipanel(app.LeftPanel);
            app.InputPanel.Title = 'Input';
            app.astrocyteButton = uibutton(app.InputPanel, 'push');
            app.astrocyteButton.ButtonPushedFcn = createCallbackFcn(app, @astrocyteButtonPushed, true);
            app.astrocyteButton.Text = 'astrocyte';
            app.mitochondriaButton = uibutton(app.InputPanel, 'push');
            app.mitochondriaButton.ButtonPushedFcn = createCallbackFcn(app, @mitochondriaButtonPushed, true);
            app.mitochondriaButton.Text = 'mitochondria';
            app.astrotextarea = uitextarea(app.InputPanel);
            app.mitochondriaTextArea = uitextarea(app.InputPanel);
            app.loaddataButton = uibutton(app.InputPanel, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.MitochondriadetectionPanel = uipanel(app.LeftPanel);
            app.MitochondriadetectionPanel.Title = 'Mitochondria detection';
            app.DetectmitochondriaButton = uibutton(app.MitochondriadetectionPanel, 'push');
            app.DetectmitochondriaButton.ButtonPushedFcn = createCallbackFcn(app, @DetectmitochondriaButtonPushed, true);
            app.DetectmitochondriaButton.Text = 'Detect mitochondria';
            app.Branch1stDetectionPanel = uipanel(app.LeftPanel);
            app.Branch1stDetectionPanel.Title = 'Branch 1st Detection';
            app.DetectbranchesButton = uibutton(app.Branch1stDetectionPanel, 'push');
            app.DetectbranchesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectbranchesButtonPushed, true);
            app.DetectbranchesButton.Text = 'Detect branches';
            app.BranchTuning1stPanel = uipanel(app.LeftPanel);
            app.BranchTuning1stPanel.Title = 'Branch Tuning 1st ';
            app.WidthThresholdSpinnerLabel = uilabel(app.BranchTuning1stPanel);
            app.WidthThresholdSpinnerLabel.Text = 'Width Threshold';
            app.WidthThresholdSpinner = uispinner(app.BranchTuning1stPanel);
            app.WidthThresholdSpinner.Step = 0.1;
            app.WidthThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthThresholdSpinnerValueChanged, true);
            app.WidthThresholdSpinner.Value = 1.5;
            app.RefinebranchesButton = uibutton(app.BranchTuning1stPanel, 'push');
            app.RefinebranchesButton.ButtonPushedFcn = createCallbackFcn(app, @RefinebranchesButtonPushed, true);
            app.RefinebranchesButton.Text = 'Refine branches';
            app.AstrocytedetectionPanel = uipanel(app.LeftPanel);
            app.AstrocytedetectionPanel.Title = 'Astrocyte detection';
            app.DetectAstrocyteButton = uibutton(app.AstrocytedetectionPanel, 'push');
            app.DetectAstrocyteButton.ButtonPushedFcn = createCallbackFcn(app, @DetectAstrocyteButtonPushed, true);
            app.DetectAstrocyteButton.Text = 'Detect Astrocyte ';
            app.MaskInputSkiptheastrocytedetectionPanel = uipanel(app.LeftPanel);
            app.MaskInputSkiptheastrocytedetectionPanel.Title = 'Mask Input (Skip the astrocyte detection)';
            app.astrocyte_maskButton = uibutton(app.MaskInputSkiptheastrocytedetectionPanel, 'push');
            app.astrocyte_maskButton.ButtonPushedFcn = createCallbackFcn(app, @astrocyte_maskButtonPushed, true);
            app.astrocyte_maskButton.Text = 'astrocyte_mask';
            app.astrocyteTextArea = uitextarea(app.MaskInputSkiptheastrocytedetectionPanel);
            app.loadmasksandastrocyterawdataButton = uibutton(app.MaskInputSkiptheastrocytedetectionPanel, 'push');
            app.loadmasksandastrocyterawdataButton.ButtonPushedFcn = createCallbackFcn(app, @loadmasksandastrocyterawdataButtonPushed, true);
            app.loadmasksandastrocyterawdataButton.Text = 'load masks and astrocyte raw data';
            app.astrocyte_dataButton = uibutton(app.MaskInputSkiptheastrocytedetectionPanel, 'push');
            app.astrocyte_dataButton.ButtonPushedFcn = createCallbackFcn(app, @astrocyte_dataButtonPushed, true);
            app.astrocyte_dataButton.Text = 'astrocyte_data';
            app.astrocyteDataTextArea = uitextarea(app.MaskInputSkiptheastrocytedetectionPanel);
            app.mito_maskButton = uibutton(app.MaskInputSkiptheastrocytedetectionPanel, 'push');
            app.mito_maskButton.ButtonPushedFcn = createCallbackFcn(app, @mito_maskButtonPushed, true);
            app.mito_maskButton.Text = 'mito_mask';
            app.mito_maskTextArea = uitextarea(app.MaskInputSkiptheastrocytedetectionPanel);
            app.Branch2ndDetectionPanel = uipanel(app.LeftPanel);
            app.Branch2ndDetectionPanel.Title = 'Branch 2nd Detection';
            app.WidthThresholdSpinner_2Label = uilabel(app.Branch2ndDetectionPanel);
            app.WidthThresholdSpinner_2Label.Text = 'Width Threshold';
            app.WidthThresholdSpinner_2 = uispinner(app.Branch2ndDetectionPanel);
            app.WidthThresholdSpinner_2.ValueChangedFcn = createCallbackFcn(app, @WidthThresholdSpinner_2ValueChanged, true);
            app.WidthThresholdSpinner_2.Value = 1;
            app.RefinebranchesButton_2 = uibutton(app.Branch2ndDetectionPanel, 'push');
            app.RefinebranchesButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefinebranchesButton_2Pushed, true);
            app.RefinebranchesButton_2.Text = 'Refine branches';
            app.QuantificationPanel = uipanel(app.LeftPanel);
            app.QuantificationPanel.Title = 'Quantification ';
            app.runButton = uibutton(app.QuantificationPanel, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.RightPanel = uipanel(app.GridLayout);
            app.visualizationPanel = uipanel(app.RightPanel);
            app.visualizationPanel.Title = 'visualization';
            app.UIAxes = uiaxes(app.visualizationPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seg_astro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end